classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        pin25Slider       matlab.ui.control.Slider
        pin25SliderLabel  matlab.ui.control.Label
        pin23Slider       matlab.ui.control.Slider
        pin23SliderLabel  matlab.ui.control.Label
        pin22Slider       matlab.ui.control.Slider
        pin22SliderLabel  matlab.ui.control.Label
        pin21Slider       matlab.ui.control.Slider
        pin21SliderLabel  matlab.ui.control.Label
        pin34Gauge        matlab.ui.control.Gauge
        pin34GaugeLabel   matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduinoObj % Arduino object
        inputTimer % Timer for real-time updates
        timeData       % Array for time data (x-axis)
        gaugeData      % Array for gauge values (y-axis)
    end
    
    methods (Access = private)
        
        function updateInputPin34(app)
            % Take multiple readings and average them
            numReadings = 1;
            totalValue = 0;
            for i = 1:numReadings
                totalValue = totalValue + readVoltage(app.arduinoObj, 'D34');
            end
            inputValue = totalValue / numReadings;
    
            % Update the gauge display with the analog value
            app.pin34Gauge.Value = inputValue; 
            
            
            % Update time and gauge data
            if isempty(app.timeData)
                app.timeData = 0;  % Start time at 0
            else
                app.timeData(end+1) = app.timeData(end) + 0.1;  % Increment time by timer period
            end
            app.gaugeData(end+1) = inputValue;  % Store current gauge value

            % Plot updated data on UIAxes
            plot(app.UIAxes, app.timeData, app.gaugeData, '-o');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Pin 34 Gauge Value');
            
            % Optional: Adjust axes limits to show last 10 seconds
            xlim(app.UIAxes, [max(0, app.timeData(end) - 10), app.timeData(end)]);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check if a connection to the ESP32 already exists and clear it
            if isempty(app.arduinoObj) || (~isempty(app.arduinoObj) && ~isvalid(app.arduinoObj))
                app.arduinoObj = arduino('COM3', 'ESP32-WROOM-DevKitV1'); % Adjust COM port and board type as needed
            end

            % Check if Timer exists and is valid, otherwise create it
            if isempty(app.inputTimer) || (~isempty(app.inputTimer) && ~isvalid(app.inputTimer))
                app.inputTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, ...
                    'TimerFcn', @(~,~) updateInputPin34(app));
                start(app.inputTimer);
            end
    
            % Configure Timer for Real-Time Input Reading
            app.inputTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, ...
                'TimerFcn', @(~,~) updateInputPin34(app));
            start(app.inputTimer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        % Stop and delete the timer if it exists and is valid
        if ~isempty(app.inputTimer) && isvalid(app.inputTimer)
            stop(app.inputTimer);
            delete(app.inputTimer);
        end

        % Clear the Arduino object if it exists and is valid
        if ~isempty(app.arduinoObj) && isvalid(app.arduinoObj)
            clear app.arduinoObj;
        end

        % Close the app
        delete(app);
        end

        % Value changed function: pin21Slider
        function pwmSlider1ValueChanged(app, event)
            pwmValue1 = app.pin21Slider.Value; % Get slider value
            dutyCycle = pwmValue1 / 255; % Scale to 0-1 for duty cycle
            writePWMDutyCycle(app.arduinoObj, 'D21', dutyCycle); % Set duty cycle
        end

        % Value changed function: pin22Slider
        function pin22SliderValueChanged(app, event)
            pwmValue2 = app.pin22Slider.Value; % Get slider value
            dutyCycle = pwmValue2 / 255; % Scale to 0-1 for duty cycle
            writePWMDutyCycle(app.arduinoObj, 'D22', dutyCycle); % Set duty cycle
        end

        % Value changed function: pin23Slider
        function pin23SliderValueChanged(app, event)
            pwmValue3 = app.pin23Slider.Value; % Get slider value
            dutyCycle = pwmValue3 / 255; % Scale to 0-1 for duty cycle
            writePWMDutyCycle(app.arduinoObj, 'D23', dutyCycle); % Set duty cycle
        end

        % Value changed function: pin25Slider
        function pin25SliderValueChanged(app, event)
            pwmValue4 = app.pin23Slider.Value; % Get slider value
            dutyCycle = pwmValue4 / 255; % Scale to 0-1 for duty cycle
            writePWMDutyCycle(app.arduinoObj, 'D25', dutyCycle); % Set duty cycle
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'V')
            zlabel(app.UIAxes, 'Z')
            app.pin34GaugeLabel = uilabel(app.UIFigure);
            app.pin34GaugeLabel.Text = 'pin34';
            app.pin34Gauge = uigauge(app.UIFigure, 'circular');
            app.pin34Gauge.Limits = [0 3.3];
            app.pin21SliderLabel = uilabel(app.UIFigure);
            app.pin21SliderLabel.Text = 'pin21';
            app.pin21Slider = uislider(app.UIFigure);
            app.pin21Slider.Limits = [0 255];
            app.pin21Slider.ValueChangedFcn = createCallbackFcn(app, @pwmSlider1ValueChanged, true);
            app.pin22SliderLabel = uilabel(app.UIFigure);
            app.pin22SliderLabel.Text = 'pin22';
            app.pin22Slider = uislider(app.UIFigure);
            app.pin22Slider.Limits = [0 255];
            app.pin22Slider.ValueChangedFcn = createCallbackFcn(app, @pin22SliderValueChanged, true);
            app.pin23SliderLabel = uilabel(app.UIFigure);
            app.pin23SliderLabel.Text = 'pin23';
            app.pin23Slider = uislider(app.UIFigure);
            app.pin23Slider.Limits = [0 255];
            app.pin23Slider.ValueChangedFcn = createCallbackFcn(app, @pin23SliderValueChanged, true);
            app.pin25SliderLabel = uilabel(app.UIFigure);
            app.pin25SliderLabel.Text = 'pin25';
            app.pin25Slider = uislider(app.UIFigure);
            app.pin25Slider.Limits = [0 255];
            app.pin25Slider.ValueChangedFcn = createCallbackFcn(app, @pin25SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end